classdef TickTock_BeauVer1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BeautyVerOneUIFigure  matlab.ui.Figure
        SysInfoLabel          matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        TabGroup              matlab.ui.container.TabGroup
        Tick                  matlab.ui.container.Tab
        Button                matlab.ui.control.Button
        STARTLabel            matlab.ui.control.Label
        TRACKButton           matlab.ui.control.Button
        Gauge                 matlab.ui.control.LinearGauge
        LogAnalysisLabel      matlab.ui.control.Label
        ViewLogButton         matlab.ui.control.Button
        TRACKLabel            matlab.ui.control.Label
        CLEARButton           matlab.ui.control.Button
        ResortButton          matlab.ui.control.StateButton
        ADDLOGButton          matlab.ui.control.Button
        TOCKTab               matlab.ui.container.Tab
        Label2                matlab.ui.control.Label
    end

    %  1,-200,1080,900 背景可用
    %
    properties (Access = private)
        % 更新时间柱
        Timer_01 = timer('StartDelay', 1, 'Period', 1,...
            'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');F
        Button_Down = false; % 按钮状态
        
        % 时间统计
        StartTick = '';   % 计时器启动时间
        EndTick = '';     % 计时器结束时间
        ItemDuration = 0; % 持续时间 min
        ItemName char ;   % 事件
        ItemDetail char;  % 事件详情
        
        % 更行系统显示时间
        Timer_02 = timer('StartDelay', 1, 'Period', 1,...
            'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
        LogPath char;  % 日志路径
        LogFile char;  % 完整日志文件
    end
    
    methods (Access = private)
        function ImageBackGroundTab_01(app)
            try
                imshow('D:\Codes\MatlabFiles\Blocks\TickTockFiles\Wallpaper_97.jpg',...
                    'Parent',app.UIAxes);
            catch EM
                fprintf('error message:%s\n',EM.identifier);
            end
        end
        
        
        % 状态显示信息
        function UpdateSysInfo(app,info)
            if ischar(info)
                app.SysInfoLabel.Text = info;
            end
        end
        
        %状态标志，用于记录，记录按钮是否按下
        function InitStates(app)
            app.Button_Down = false;
        end
        
        % 时间柱
        function DrawDurationBar_01(app)
            temp = etime(datevec(datestr(datetime('now'),'yy/mm/dd  HH:MM:SS')),...
                datevec(app.StartTick));
            app.ItemDuration = int32(temp/60); % min
            app.Gauge.Value = app.ItemDuration;
        end
        
        % 系统时间
        function SystemTimeUpdate(app)
            app.Label.Text = datestr(datetime('now'),'HH:MM:SS');
        end
        
        % 记录日志
        function KeepItemInfo(app)
            %{
                logpath选择需要摆脱文件夹路径限制
            %}
            fileID = fopen(app.LogFile,'a+');
            if ~isequal(fileID,0)
                % 时间|事项|详情|持续时间
                StTemp = app.StartTick;
                EtTemp = app.EndTick;
                DutationtTemp = app.ItemDuration;
                ItemNameTemp =  app.ItemName;
                ItemDetailTemp = app.ItemDetail;
                
                fprintf(fileID,'%s\t|\t%s\t|\t%s\t|\t%s\t|\t%d\n',StTemp...
                    ,EtTemp,ItemNameTemp,ItemDetailTemp,DutationtTemp);
                disp('file open succeed...')
            end
            fclose(fileID);
        end
        
        % 初始化日志文件
        function  InitLogFile(app)
            %{
                两种处理方式：
                一：相对路径方式（最优）
                二：固定路径方式（先用）
            %}
            app.LogPath = ' D:\Codes\MatlabFiles\Blocks\TickTockFiles\Logs';
            [status,msg] = mkdir(app.LogPath);
            UpdateSysInfo(app,msg);
            % 新建日志文件
            try
                curlog = datestr(datetime('now'),'yyyy-mm-dd');
                curlog = strcat(curlog,'.txt');
                exitstatus = 0;
                dirtemp = dir(app.LogPath);
                % 当前文件夹中是否包含当天日志文件
                for i = 3:1:length(dirtemp)
                    temp = dirtemp(i).name;
                    if strcmp(temp,curlog)
                        exitstatus = 1;
                        break;
                    end
                end
                
                if exitstatus == 0
                    fileID = fopen(fullfile('D:\Codes\MatlabFiles\Blocks\TickTockFiles\Logs',...
                        curlog),'w');
                    fclose(fileID);
                end
                app.LogFile = fullfile('D:\Codes\MatlabFiles\Blocks\TickTockFiles\Logs',...
                    curlog);
            catch EM
                EM.identifier
            end
            info_temp = '日志初始化完成';
            UpdateSysInfo(app,info_temp);
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            disp('tick tock beauty version')
            ImageBackGroundTab_01(app)
            
            % 启动时间柱
            app.Timer_01.TimerFcn = @(~, ~) DrawDurationBar_01(app);
            
            % 启动系统时间显示
            app.Timer_02.TimerFcn = @(~,~)SystemTimeUpdate(app);
            start(app.Timer_02);
            InitLogFile(app);
        end

        % Close request function: BeautyVerOneUIFigure
        function BeautyVerOneUIFigureCloseRequest(app, event)
            delete(app)
            delete(timerfindall())
        end

        % Button pushed function: TRACKButton
        function TRACKButtonPushed(app, event)
            if isequal(app.STARTLabel.Text,'STOP')
                UpdateSysInfo(app,'计时中，按钮不可用')
            else
                if app.ItemDuration < 1
                   	UpdateSysInfo(app,'记录时间 < 1min，记录按钮不可用')
                else
                    prompt_01 = {'ItemName','ItemDetail'};
                    title_01 = 'KEEP      INFO';
                    dims_01 = [1 40];
               	    answer_01 = inputdlg(prompt_01,title_01,dims_01);
               	    if isempty(answer_01)
                      		return;
               	    end
               	    ansPartOne = answer_01{1};
               	    ansPartTwo = answer_01{2};
               	    if isempty(ansPartOne) || isempty(ansPartTwo)
                      		return;
               	    else
                      		app.ItemName = ansPartOne;
                      		app.ItemDetail = ansPartTwo;
               	    end
               	    answer_02 = questdlg('Track the Info',...
                       	'KEEP   INFO','YES','NO','NO');
                    
                    if isequal(answer_02,'YES')
                        KeepItemInfo(app);
                        DutationtTemp = app.ItemDuration;
                        ItemNameTemp =  app.ItemName;
                        ItemDetailTemp = app.ItemDetail;
                        info_temp = strcat(ItemNameTemp,'|',...
                            ItemDetailTemp,'|',num2str(DutationtTemp));
                        UpdateSysInfo(app,info_temp);
                        
                        % 清除信息
                        app.ItemName = '';
                        app.ItemDetail = '';
                        app.ItemDuration = 0;
                        app.Gauge.Value = 0;
                    end
                end
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value = app.Button_Down;
            State = {'START','STOP'};
            if value
                % 启动计时器状态中
                app.STARTLabel.Text = State{1};
                app.Button.BackgroundColor = [0.47,0.67,0.19];
                app.EndTick = datestr(datetime('now'),'yy/mm/dd  HH:MM:SS');
                
                if ~isempty(timerfind(app.Timer_01))
                    stop(app.Timer_01);
                end
            else
                app.STARTLabel.Text = State{2};
                app.Button.BackgroundColor = [0.64,0.08,0.18];
                
                % 停止计时器状态中
                app.ItemDuration = 0;
                app.StartTick = datestr(datetime('now'),'yy/mm/dd  HH:MM:SS');
                start(app.Timer_01);
                info_temp = '计时中......';
                UpdateSysInfo(app,info_temp);
                
            end
            app.Button_Down = ~value;
            
        end

        % Button pushed function: ViewLogButton
        function Button_3Pushed(app, event)
            if false
                [file,path] = uigetfile('*.txt');
                if ~isequal(file,0)
                    winopen(app.LogFile);
                    info_temp = '打开日志文件';
                    info_temp = info_temp + fullfile(path,file);
                else
                    info_temp = '取消查看日志';
                end
                UpdateSysInfo(app,info_temp);
            else
                try
                    winopen(app.LogFile);
                    info_temp = '打开日志文件';
                    info_temp = strcat(info_temp , app.LogFile);
                catch EM
                    info_temp =  EM.identifier;
                end
                UpdateSysInfo(app,info_temp);
            end
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            if isequal(app.STARTLabel.Text,'STOP')
                
                info_temp = '计时中，清除Track信息失败';
            else
                % 清除信息
                app.ItemName = '';
                app.ItemDetail = '';
                app.ItemDuration = 0;
                app.Gauge.Value = 0;
                info_temp = '清除计时信息完成';
            end
            UpdateSysInfo(app,info_temp);
        end

        % Button pushed function: ADDLOGButton
        function ADDLOGButtonPushed(app, event)
            % 将所有外部日志信息规范化之后按照日志日期进行记录
            %{
                e.g:
            %}
            info_temp = '此功能暂未开放....';
            
            UpdateSysInfo(app,info_temp);
            return;           
            defaultfile = 'C:\Users\10520\Desktop\timetabletemp.txt';
            [file,path] = uigetfile(defaultfile);
            if ~isequal(file,0)
                info_temp = 'manual opration of log info';
                info_temp = strcat(info_temp,fullfile(path,file));
                LoadLogFlag = 1;
                waitbar_1 = waitbar(0,'Handling.....');
                try
                    log_temp = readtable(defaultfile);
                catch EM
                    into_temp = EM.identifier;
                    LoadLogFlag = 0;
                end
                
                if LoadLogFlag == 1  % 加载成功
                    waitbar(0.25,waitbar_1,'Loading log finished.....');
                    
                    waitbar(0.5,waitbar_1,'handling');
                    
                    waitbar(1,waitbar_1,'Finished');
                    
                    into_temp = '手动添加日志完成.....';
                    
                    pause(1)
                end
                close(waitbar_1);
            else
                info_temp = 'cancle manual operation';
            end
            
            UpdateSysInfo(app,info_temp);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BeautyVerOneUIFigure = uifigure;
            app.BeautyVerOneUIFigure.Name = 'BeautyVerOne';
            app.BeautyVerOneUIFigure.CloseRequestFcn = createCallbackFcn(app, @BeautyVerOneUIFigureCloseRequest, true);
            app.SysInfoLabel = uilabel(app.BeautyVerOneUIFigure);
            app.SysInfoLabel.Text = 'SysInfo';
            app.Label = uilabel(app.BeautyVerOneUIFigure);
            app.TabGroup = uitabgroup(app.BeautyVerOneUIFigure);
            app.Tick = uitab(app.TabGroup);
            app.Tick.Title = 'TICK';
            app.Button = uibutton(app.Tick, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'power_button.png';
            app.Button.Text = '';
            app.STARTLabel = uilabel(app.Tick);
            app.STARTLabel.Text = 'START';
            app.TRACKButton = uibutton(app.Tick, 'push');
            app.TRACKButton.ButtonPushedFcn = createCallbackFcn(app, @TRACKButtonPushed, true);
            app.TRACKButton.Text = 'TRACK';
            app.Gauge = uigauge(app.Tick, 'linear');
            app.Gauge.Limits = [0 239];
            app.Gauge.MinorTicks = [];
            app.LogAnalysisLabel = uilabel(app.Tick);
            app.LogAnalysisLabel.Text = {'————————————————————————————————————————————'; 'Log Analysis'; '————————————————————————————————————————————'};
            app.ViewLogButton = uibutton(app.Tick, 'push');
            app.ViewLogButton.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.ViewLogButton.Text = 'View-Log';
            app.TRACKLabel = uilabel(app.Tick);
            app.TRACKLabel.Text = {'————————————————————————————————————————————'; 'TRACK'; '————————————————————————————————————————————'};
            app.CLEARButton = uibutton(app.Tick, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.ResortButton = uibutton(app.Tick, 'state');
            app.ResortButton.Text = 'Resort';
            app.ADDLOGButton = uibutton(app.Tick, 'push');
            app.ADDLOGButton.ButtonPushedFcn = createCallbackFcn(app, @ADDLOGButtonPushed, true);
            app.ADDLOGButton.Text = 'ADD-LOG';
            app.TOCKTab = uitab(app.TabGroup);
            app.TOCKTab.Title = 'TOCK';
            app.Label2 = uilabel(app.TOCKTab);
            app.Label2.Text = {'Another summer day'; 'Has come and gone away'; 'In Paris or Rome...'; 'But I wanna go home'; 'uhm Home'; 'May be surrounded by'; 'A million people I'; 'Still feel all alone'; 'Just wanna go home'; 'I miss you, you know'; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = TickTock_BeauVer1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BeautyVerOneUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BeautyVerOneUIFigure)
        end
    end
end